classdef ISM_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DrySignalPanel                matlab.ui.container.Panel
        ListenButton                  matlab.ui.control.Button
        InstrumentDropDownLabel       matlab.ui.control.Label
        InstrumentDropDown            matlab.ui.control.DropDown
        WetSignalPanel                matlab.ui.container.Panel
        ListenButton_2                matlab.ui.control.Button
        InteractiveRIRgeneratorLabel  matlab.ui.control.Label
        GenerateRIRButton             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        ConvolveButton                matlab.ui.control.Button
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        LargeEditFieldLabel           matlab.ui.control.Label
        LargeEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        Src_zEditFieldLabel           matlab.ui.control.Label
        Src_zEditField                matlab.ui.control.NumericEditField
        Src_yEditFieldLabel           matlab.ui.control.Label
        Src_yEditField                matlab.ui.control.NumericEditField
        Src_xEditFieldLabel           matlab.ui.control.Label
        Src_xEditField                matlab.ui.control.NumericEditField
        coRef_xEditFieldLabel         matlab.ui.control.Label
        coRef_xEditField              matlab.ui.control.NumericEditField
        coRef_yEditFieldLabel         matlab.ui.control.Label
        coRef_yEditField              matlab.ui.control.NumericEditField
        coRef_zEditFieldLabel         matlab.ui.control.Label
        coRef_zEditField              matlab.ui.control.NumericEditField
        SamplesSlider                 matlab.ui.control.Slider
        PARAMETERSLabel               matlab.ui.control.Label
    end


    properties (Access = private)
        drySignal % Contains the audio file handle to be processed
        wetSignal % Contains the processed signal
        Fs        % Sample Rate
        IR        % IR vector
    end
    
    methods (Access = private)
        
        function h = ISM(app,mic_x,mic_y,mic_z)
            % IR vector initialization
            h = [];
            
            % Sound velocity
            cSound = 340;
            % Air damping coefficient
            airDamp = 0.007;
            % Number of reflections per axis
            N = 40;
            n = -N:N;
            [l,m,n] = meshgrid(n,n,n);
            % Absorption coefficients
            coefAbsor = [app.coRef_xEditField.Value,...
                app.coRef_yEditField.Value,app.coRef_zEditField.Value];
            
            % IR obtention procedures
            
            % Image cell coordinates
            dx = ((app.WidthEditField.Value*l)+...
                (((-1).^l)*app.Src_xEditField.Value))-mic_x;
            dy = ((app.LargeEditField.Value*m)+...
                (((-1).^m)*app.Src_yEditField.Value))-mic_y;
            dz = ((app.HeightEditField.Value*n)+...
                (((-1).^n)*app.Src_zEditField.Value))-mic_z;
            % Distances from image cell to listener
            Dlmn = sqrt((dx.^2)+(dy.^2)+(dz.^2));
            % Room absorption function per axis
            coefAbsor = -abs(coefAbsor);
            absorFunc_x = coefAbsor(1).^...
                (abs(0.5.*l - 0.25 + 0.25.*(-1).^l));
            absorFunc_y = coefAbsor(2).^...
                (abs(0.5.*m - 0.25 + 0.25.*(-1).^m));
            absorFunc_z = coefAbsor(3).^...
                (abs(0.5.*n - 0.25 + 0.25.*(-1).^n));
            absorFunc = absorFunc_x.*absorFunc_y.*absorFunc_z;
            % Air damping function
            airDampFunc = exp(-airDamp*Dlmn);
            % Damping coefficient
            P = (1./(4*pi*(Dlmn.^2))).*absorFunc.*airDampFunc;
            % Delays due to distance
            realTime = Dlmn/cSound;
            nSample = round(realTime*app.Fs,0);
            % IR obtention
            h(nSample) = P;
            % IR normalization
            h = h/max(abs(h));
        end
        
    end


    methods (Access = private)

        % Button pushed function: ListenButton
        function ListenButtonPushed(app, event)
            sound(app.drySignal,app.Fs);
        end

        % Value changed function: InstrumentDropDown
        function InstrumentDropDownValueChanged2(app, event)
            value = app.InstrumentDropDown.Value;
            switch value
                case 'ElecGtr'
                    [app.drySignal,app.Fs]=audioread('ElecGtr.wav');
                case 'Drums'
                    [app.drySignal,app.Fs]=audioread('Drums.mp3');
            end
            % Stereo to mono conversion
            app.drySignal = mean(app.drySignal,2);
        end

        % Button pushed function: GenerateRIRButton
        function GenerateRIRButtonPushed(app, event)
            app.IR = ISM(app,-3,-3,-3);
            plot(app.UIAxes,app.IR),ylim(app.UIAxes,[-1,1]);
            app.ListenButton_2.BackgroundColor = [0.94,0.94,0.94];
        end

        % Button pushed function: ConvolveButton
        function ConvolveButtonPushed(app, event)
            app.wetSignal = fftfilt(app.IR,app.drySignal);
            % Preventive normalization
            app.wetSignal = (app.wetSignal/max(abs(app.wetSignal)))*0.87;
            app.ListenButton_2.BackgroundColor = [0.47,0.67,0.19];
        end

        % Button pushed function: ListenButton_2
        function ListenButton_2Pushed(app, event)
            sound(app.wetSignal,app.Fs);
        end

        % Value changing function: SamplesSlider
        function SamplesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.XLim = [0,changingValue];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DrySignalPanel = uipanel(app.UIFigure);
            app.DrySignalPanel.Title = 'Dry Signal';
            app.ListenButton = uibutton(app.DrySignalPanel, 'push');
            app.ListenButton.ButtonPushedFcn = createCallbackFcn(app, @ListenButtonPushed, true);
            app.ListenButton.Text = 'Listen';
            app.InstrumentDropDownLabel = uilabel(app.DrySignalPanel);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.DrySignalPanel);
            app.InstrumentDropDown.Items = {'   -Select-', 'Drums', 'ElecGtr'};
            app.InstrumentDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentDropDownValueChanged2, true);
            app.InstrumentDropDown.Value = '   -Select-';
            app.WetSignalPanel = uipanel(app.UIFigure);
            app.WetSignalPanel.Title = 'Wet Signal';
            app.ListenButton_2 = uibutton(app.WetSignalPanel, 'push');
            app.ListenButton_2.ButtonPushedFcn = createCallbackFcn(app, @ListenButton_2Pushed, true);
            app.ListenButton_2.Text = 'Listen';
            app.InteractiveRIRgeneratorLabel = uilabel(app.UIFigure);
            app.InteractiveRIRgeneratorLabel.Text = 'Interactive RIR generator';
            app.GenerateRIRButton = uibutton(app.UIFigure, 'push');
            app.GenerateRIRButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRIRButtonPushed, true);
            app.GenerateRIRButton.Text = 'Generate RIR';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Room Impulse Response')
            xlabel(app.UIAxes, 'Time (# Samples)')
            ylabel(app.UIAxes, 'P')
            app.ConvolveButton = uibutton(app.UIFigure, 'push');
            app.ConvolveButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolveButtonPushed, true);
            app.ConvolveButton.Text = 'Convolve';
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.Value = 20;
            app.LargeEditFieldLabel = uilabel(app.UIFigure);
            app.LargeEditFieldLabel.Text = 'Large';
            app.LargeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LargeEditField.Value = 20;
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.Value = 20;
            app.Src_zEditFieldLabel = uilabel(app.UIFigure);
            app.Src_zEditFieldLabel.Text = 'Src_z';
            app.Src_zEditField = uieditfield(app.UIFigure, 'numeric');
            app.Src_zEditField.Value = 5;
            app.Src_yEditFieldLabel = uilabel(app.UIFigure);
            app.Src_yEditFieldLabel.Text = 'Src_y';
            app.Src_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.Src_yEditField.Value = 5;
            app.Src_xEditFieldLabel = uilabel(app.UIFigure);
            app.Src_xEditFieldLabel.Text = 'Src_x';
            app.Src_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.Src_xEditField.Value = 5;
            app.coRef_xEditFieldLabel = uilabel(app.UIFigure);
            app.coRef_xEditFieldLabel.Text = 'coRef_x';
            app.coRef_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.coRef_xEditField.Value = 0.5;
            app.coRef_yEditFieldLabel = uilabel(app.UIFigure);
            app.coRef_yEditFieldLabel.Text = 'coRef_y';
            app.coRef_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.coRef_yEditField.Value = 0.5;
            app.coRef_zEditFieldLabel = uilabel(app.UIFigure);
            app.coRef_zEditFieldLabel.Text = 'coRef_z';
            app.coRef_zEditField = uieditfield(app.UIFigure, 'numeric');
            app.coRef_zEditField.Value = 0.5;
            app.SamplesSlider = uislider(app.UIFigure);
            app.SamplesSlider.Limits = [0 500000];
            app.SamplesSlider.ValueChangingFcn = createCallbackFcn(app, @SamplesSliderValueChanging, true);
            app.SamplesSlider.Value = 500000;
            app.PARAMETERSLabel = uilabel(app.UIFigure);
            app.PARAMETERSLabel.Text = 'PARAMETERS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ISM_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end